classdef Combinado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultadosPanel                 matlab.ui.container.Panel
        RS                              matlab.ui.control.EditField
        ResultadoSimpsonEditFieldLabel  matlab.ui.control.Label
        RL                              matlab.ui.control.EditField
        ResultadoLegendreEditFieldLabel  matlab.ui.control.Label
        RT                              matlab.ui.control.EditField
        ResultadoTrapecioEditFieldLabel  matlab.ui.control.Label
        GrficaPanel                     matlab.ui.container.Panel
        IngresodeDatosPanel             matlab.ui.container.Panel
        b                               matlab.ui.control.NumericEditField
        HastabEditFieldLabel            matlab.ui.control.Label
        a                               matlab.ui.control.NumericEditField
        DesdeaEditFieldLabel            matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        nl                              matlab.ui.control.NumericEditField
        NumerodeTrapeciosPuntosyPolinomiosLabel  matlab.ui.control.Label
        Ecuacion                        matlab.ui.control.EditField
        EcuacinLabel                    matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function ecuacionX = interpolacionLagrange(app,xLi,fxi)
            nL=length(xLi);
            syms x;
            
            for i=1:nL
                Li=1;
                for j=1:nL
                    if j~=i
                        Li=Li*((x-xLi(j))/(xLi(i)-xLi(j)));
                    end
                end
                L(i)=Li;
            end
            
            ecuacionX=0;
            for i=1:nL
                ecuacionX=L(i)*fxi(i)+ecuacionX;
            end
            ecuacionX=simplify(expand(ecuacionX));
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            y = inline(app.Ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            
            %% Método Trapecio
            nl = app.nl.Value;
            h=abs(b-a)/nl;
            sum = 0;
            for i=1:nl-1
                sum = sum + y(a+i*h);
            end
            rt = (h/2)*(y(a)+2*sum+y(b));
            app.RT.Value = num2str(rt);
            
            %% Gráficas
            X1=a:h:b;
            x=a:0.01:b;
            
            ax1 = subplot(1,3,1,'Parent',app.GrficaPanel);
            ax2 = subplot(1,3,2,'Parent',app.GrficaPanel);
            ax3 = subplot(1,3,3,'Parent',app.GrficaPanel);
            
            area(ax1, X1, feval(y,X1),'FaceColor', '#4DBEEE','LineStyle',"--");
            hold (ax1, 'on');
            plot(ax1,X1, feval(y,X1),"r",'LineWidth',1,'LineStyle',"--");
            plot(ax1,x, feval(y,x),"k",'LineWidth',2);
            title(ax1,'Trapecio');
             hold (ax1,'off');
            %% Método Legendre
            f = inline(app.Ecuacion.Value);
            nl = app.nl.Value;
            switch nl
                case 2
                    w=[1 1];
                    z=[-0.5773502692 0.5773502692];
                case 3
                    w=[0.5555555556 0.8888888889 0.5555555556];
                    z=[-0.774596692 0 0.774596692];
                case 4
                    w=[0.3478548451 0.6521451549 0.6521451549 0.3478548451];
                    z=[-0.8611363116 -0.3399810436 0.3399810436 0.8611363116];
                case 5
                    w=[0.2369268851 0.4786286705 0.5688888888 0.4786286705 0.2369268851];
                    z=[-0.9061798459 -0.5384693101 0 0.5384693101 0.9061798459];
                case 6
                    w=[0.1713244924 0.3607615730 0.4679139346 0.4679139346 0.3607615730 0.1713244924];
                    z=[-0.9324695142 -0.66120938865 -0.2386191861 0.2386191861 0.6612093865 0.9324695142];
                case 7
                    w=[0.1294849662 0.2797053915 0.3818300505 0.4179591837 0.3818300505 0.2797053915 0.1294849662];
                    z=[-0.9491079123 -0.7415311856 -0.4058451514 0 0.4058451514 0.7415311856 0.9491079123];
                case 8
                    w=[0.1012285363 0.2223810345 0.313706659 0.3626837834 0.3626837834 0.313706659 0.2223810345 0.1012285363];
                    z=[-0.9602898565 -0.7966664774 -0.5255324099 -0.1834346425 0.1834346425 0.5255324099 0.7966664774 0.9602898565];
            end
            suma = 0;
            for i=1:nl
                X(i) = ((b-a)/2) * z(i) + ((b+a)/2);
                fx(i) = f(X(i));
                suma = suma + w(i)*fx(i);
            end
            rl = ((b-a)/2)*suma;
            app.RL.Value = num2str(rl);
            
            x = a:0.02:b;
            area(ax2,X, feval(f,X), 'FaceColor', '#4DBEEE');
            hold (ax2, 'on');
            plot(ax2, X, fx, 'r', 'LineWidth',1);
            plot(ax2, x, feval(f,x), 'k', 'LineWidth',1);
            title(ax2,'Legendre');
            hold (ax2,'off');
            
            %% Método de Simpson
            g = inline(app.Ecuacion.Value);
            nl  = app.nl.Value;
            h=(b-a)/(3*nl);
            xi=0;
                for i=1:nl
                    x=a+(3*i-2)*h;
                    xi=xi+g(x);
                    %punto xn-------------------------
                    plot(ax3,x,feval(g,x),'*b');
                    hold (ax3, 'on');
                    yg=linspace(0,g(x));
                    plot(ax3,x,yg,'*b');
                    %--------------------------------
                    XV(i)=x;
                end
            xj=0;   
                for k=1:nl
                    y=a+(3*k-1)*h;
                    xj=xj+g(y);
                    %punto xn-------------------------
                    plot(ax3,y,feval(g,y),'*b');
                    yg=linspace(0,g(y));
                    plot(ax3,y,yg,'*b');
                    %--------------------------------
                    XY(k)=y;
                end
            xk=0;   
                for p=1:nl-1
                    z=a+(3*p)*h;
                    xk=xk+g(z);
                    %punto xn-------------------------
                    plot(ax3,z,feval(g,z),'*b');
                    yg=linspace(0,g(z));
                    plot(ax3,z,yg,'r');
                    %--------------------------------
                    XZ(p)=z;
                end
                %vector de x
                if nl==1
                    Xvectors=[a b XV XY];
                else
                    Xvectors=[a b XV XY XZ];
                end   
                Xvectors=sort(Xvectors);

                auxj=1;
                for i=1:nl
                    for j=1:4
                        XGi(i,j)=Xvectors(auxj);
                        auxj=auxj+1;
                        if j==4
                            auxj=auxj-1;
                        end
                    end
                    %Creacion de polinomio de 3er grado
                    Pn=interpolacionLagrange(app,XGi(i,:),g(XGi(i,:)));
                    Pn=inline(Pn);

                    %Graficar funcion de 3er grado
                    xgPn=XGi(i,1):0.01:XGi(i,4);
                    plot(ax3,xgPn, feval(Pn,xgPn),"r",'LineWidth',2); 
                    area(ax3, xgPn, feval(Pn,xgPn),'FaceColor', '#4DBEEE');
                end

                %resultado
                result=(3*h/8)*(g(a)+g(b)+3*xi+3*xj+2*xk);
                %2+sin(2*sqrt(x))
             app.RS.Value = num2str(result);


            X=a:h:b;
             x=a:0.01:b;
             %area(app.UIAxes, X, feval(g,X),'FaceColor', 'g');
             plot(ax3,x, feval(g,x),"k",'LineWidth',1);
             hold (ax3, 'on');   
             %a
             plot(ax3,a,feval(g,a),'*b');
             ya=linspace(0,g(a));
             plot(ax3,a,ya,'-b');
             hold (ax3, 'on');
             %b
             plot(ax3,b,feval(g,b),'*b');
             yb=linspace(0,g(b));
             plot(ax3,b,yb,'-b');
             hold (ax3, 'on');
             title(ax3,'Simpson');
             hold (ax3,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IngresodeDatosPanel = uipanel(app.UIFigure);
            app.IngresodeDatosPanel.Title = 'Ingreso de Datos';
            app.EcuacinLabel = uilabel(app.IngresodeDatosPanel);
            app.EcuacinLabel.Text = 'Ecuación:';
            app.Ecuacion = uieditfield(app.IngresodeDatosPanel, 'text');
            app.NumerodeTrapeciosPuntosyPolinomiosLabel = uilabel(app.IngresodeDatosPanel);
            app.NumerodeTrapeciosPuntosyPolinomiosLabel.Text = {'Numero de Trapecios,'; 'Puntos y Polinomios'};
            app.nl = uieditfield(app.IngresodeDatosPanel, 'numeric');
            app.nl.Limits = [2 8];
            app.nl.RoundFractionalValues = 'on';
            app.nl.Value = 2;
            app.CalcularButton = uibutton(app.IngresodeDatosPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.DesdeaEditFieldLabel = uilabel(app.IngresodeDatosPanel);
            app.DesdeaEditFieldLabel.Text = 'Desde (a)';
            app.a = uieditfield(app.IngresodeDatosPanel, 'numeric');
            app.HastabEditFieldLabel = uilabel(app.IngresodeDatosPanel);
            app.HastabEditFieldLabel.Text = 'Hasta (b)';
            app.b = uieditfield(app.IngresodeDatosPanel, 'numeric');
            app.GrficaPanel = uipanel(app.UIFigure);
            app.GrficaPanel.Title = 'Gráfica';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.ResultadoTrapecioEditFieldLabel = uilabel(app.ResultadosPanel);
            app.ResultadoTrapecioEditFieldLabel.Text = 'Resultado Trapecio';
            app.RT = uieditfield(app.ResultadosPanel, 'text');
            app.ResultadoLegendreEditFieldLabel = uilabel(app.ResultadosPanel);
            app.ResultadoLegendreEditFieldLabel.Text = 'Resultado Legendre';
            app.RL = uieditfield(app.ResultadosPanel, 'text');
            app.ResultadoSimpsonEditFieldLabel = uilabel(app.ResultadosPanel);
            app.ResultadoSimpsonEditFieldLabel.Text = 'Resultado Simpson';
            app.RS = uieditfield(app.ResultadosPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Combinado

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end