classdef Legendre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MtododeLegendrePanel   matlab.ui.container.Panel
        EcuacinEditFieldLabel  matlab.ui.control.Label
        ecuacion               matlab.ui.control.EditField
        DesdeaEditFieldLabel   matlab.ui.control.Label
        a                      matlab.ui.control.NumericEditField
        HastabEditFieldLabel   matlab.ui.control.Label
        b                      matlab.ui.control.NumericEditField
        Numerodepuntos28Label  matlab.ui.control.Label
        n                      matlab.ui.control.NumericEditField
        calcular               matlab.ui.control.Button
        ResultadoLabel         matlab.ui.control.Label
        resultado              matlab.ui.control.EditField
        AadirButton            matlab.ui.control.Button
        GrficaPanel            matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
            
            f = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;
            switch n
                case 2
                    w=[1 1];
                    z=[-0.5773502692 0.5773502692];
                case 3
                    w=[0.5555555556 0.8888888889 0.5555555556];
                    z=[-0.774596692 0 0.774596692];
                case 4
                    w=[0.3478548451 0.6521451549 0.6521451549 0.3478548451];
                    z=[-0.8611363116 -0.3399810436 0.3399810436 0.8611363116];
                case 5
                    w=[0.2369268851 0.4786286705 0.5688888888 0.4786286705 0.2369268851];
                    z=[-0.9061798459 -0.5384693101 0 0.5384693101 0.9061798459];
                case 6
                    w=[0.1713244924 0.3607615730 0.4679139346 0.4679139346 0.3607615730 0.1713244924];
                    z=[-0.9324695142 -0.66120938865 -0.2386191861 0.2386191861 0.6612093865 0.9324695142];
                case 7
                    w=[0.1294849662 0.2797053915 0.3818300505 0.4179591837 0.3818300505 0.2797053915 0.1294849662];
                    z=[-0.9491079123 -0.7415311856 -0.4058451514 0 0.4058451514 0.7415311856 0.9491079123];
                case 8
                    w=[0.1012285363 0.2223810345 0.313706659 0.3626837834 0.3626837834 0.313706659 0.2223810345 0.1012285363];
                    z=[-0.9602898565 -0.7966664774 -0.5255324099 -0.1834346425 0.1834346425 0.5255324099 0.7966664774 0.9602898565];
            end
            suma = 0;
            for i=1:n
                X(i) = ((b-a)/2) * z(i) + ((b+a)/2);
                fx(i) = f(X(i));
                suma = suma + w(i)*fx(i);
            end
            r = ((b-a)/2)*suma;
            app.resultado.Value = num2str(r);
            x = a:0.02:b;
            
            hold (app.UIAxes, 'on');
            if (n==2)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 2','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 2','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==3)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 3','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 3','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==4)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 4','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 4','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==5)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 5','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 5','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==6)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 6','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 6','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==7)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 7','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 7','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==8)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 8','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 8','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            end
            plot(app.UIAxes, x, feval(f,x), 'LineWidth',2,'DisplayName','fx');
            app.calcular.Enable = 'off';
            app.a.Editable = 'off';
            app.b.Editable = 'off';
            app.ecuacion.Editable = 'off';
        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
            f = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;
            switch n
                case 2
                    w=[1 1];
                    z=[-0.5773502692 0.5773502692];
                case 3
                    w=[0.5555555556 0.8888888889 0.5555555556];
                    z=[-0.774596692 0 0.774596692];
                case 4
                    w=[0.3478548451 0.6521451549 0.6521451549 0.3478548451];
                    z=[-0.8611363116 -0.3399810436 0.3399810436 0.8611363116];
                case 5
                    w=[0.2369268851 0.4786286705 0.5688888888 0.4786286705 0.2369268851];
                    z=[-0.9061798459 -0.5384693101 0 0.5384693101 0.9061798459];
                case 6
                    w=[0.1713244924 0.3607615730 0.4679139346 0.4679139346 0.3607615730 0.1713244924];
                    z=[-0.9324695142 -0.66120938865 -0.2386191861 0.2386191861 0.6612093865 0.9324695142];
                case 7
                    w=[0.1294849662 0.2797053915 0.3818300505 0.4179591837 0.3818300505 0.2797053915 0.1294849662];
                    z=[-0.9491079123 -0.7415311856 -0.4058451514 0 0.4058451514 0.7415311856 0.9491079123];
                case 8
                    w=[0.1012285363 0.2223810345 0.313706659 0.3626837834 0.3626837834 0.313706659 0.2223810345 0.1012285363];
                    z=[-0.9602898565 -0.7966664774 -0.5255324099 -0.1834346425 0.1834346425 0.5255324099 0.7966664774 0.9602898565];
            end
            suma = 0;
            for i=1:n
                X(i) = ((b-a)/2) * z(i) + ((b+a)/2);
                fx(i) = f(X(i));
                suma = suma + w(i)*fx(i);
            end
            r = ((b-a)/2)*suma;
            app.resultado.Value = num2str(r);
            x = a:0.02:b;
            hold (app.UIAxes, 'on');
            if (n==2)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 2','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 2','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==3)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 3','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 3','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==4)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 4','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 4','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==5)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 5','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 5','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==6)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 6','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 6','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==7)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 7','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 7','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            elseif (n==8)
                plot(app.UIAxes, X, fx, 'LineWidth',1,'DisplayName','Grado 8','LineStyle',"--");
                area(app.UIAxes,X, feval(f,X),'DisplayName','Area Grado 8','LineStyle',"--");
                legend(app.UIAxes,"AutoUpdate","on");
            end
            plot(app.UIAxes, x, feval(f,x), 'LineWidth',2,'DisplayName','fx');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MtododeLegendrePanel = uipanel(app.UIFigure);
            app.MtododeLegendrePanel.Title = 'Método de Legendre';
            app.EcuacinEditFieldLabel = uilabel(app.MtododeLegendrePanel);
            app.EcuacinEditFieldLabel.Text = 'Ecuación';
            app.ecuacion = uieditfield(app.MtododeLegendrePanel, 'text');
            app.DesdeaEditFieldLabel = uilabel(app.MtododeLegendrePanel);
            app.DesdeaEditFieldLabel.Text = 'Desde (a):';
            app.a = uieditfield(app.MtododeLegendrePanel, 'numeric');
            app.HastabEditFieldLabel = uilabel(app.MtododeLegendrePanel);
            app.HastabEditFieldLabel.Text = 'Hasta (b)';
            app.b = uieditfield(app.MtododeLegendrePanel, 'numeric');
            app.Numerodepuntos28Label = uilabel(app.MtododeLegendrePanel);
            app.Numerodepuntos28Label.Text = 'Numero de puntos (2 - 8):';
            app.n = uieditfield(app.MtododeLegendrePanel, 'numeric');
            app.n.Limits = [2 8];
            app.n.Value = 2;
            app.calcular = uibutton(app.MtododeLegendrePanel, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.MtododeLegendrePanel);
            app.ResultadoLabel.Text = 'Resultado:';
            app.resultado = uieditfield(app.MtododeLegendrePanel, 'text');
            app.resultado.Editable = 'off';
            app.AadirButton = uibutton(app.MtododeLegendrePanel, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Text = 'Añadir';
            app.GrficaPanel = uipanel(app.UIFigure);
            app.GrficaPanel.Title = 'Gráfica';
            app.UIAxes = uiaxes(app.GrficaPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Legendre

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end