classdef Simpson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GrficaPanel              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        MtododeSimpson38Panel    matlab.ui.container.Panel
        AadirButton              matlab.ui.control.Button
        resultado                matlab.ui.control.EditField
        ResultadoLabel           matlab.ui.control.Label
        calcular                 matlab.ui.control.Button
        n                        matlab.ui.control.NumericEditField
        NumerodePolinomiosLabel  matlab.ui.control.Label
        b                        matlab.ui.control.NumericEditField
        HastabEditFieldLabel     matlab.ui.control.Label
        a                        matlab.ui.control.NumericEditField
        DesdeaEditFieldLabel     matlab.ui.control.Label
        ecuacion                 matlab.ui.control.EditField
        EcuacinEditFieldLabel    matlab.ui.control.Label
    end


    methods (Access = public)
        
        function ecuacionX = interpolacionLagrange(app,xLi,fxi)
            nL=length(xLi);
            syms x;
            
            for i=1:nL
                Li=1;
                for j=1:nL
                    if j~=i
                        Li=Li*((x-xLi(j))/(xLi(i)-xLi(j)));
                    end
                end
                L(i)=Li;
            end
            
            ecuacionX=0;
            for i=1:nL
                ecuacionX=L(i)*fxi(i)+ecuacionX;
            end
            ecuacionX=simplify(expand(ecuacionX));
                        
         end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
            
            g = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;

            % --- Method
            h=(b-a)/(3*n);
            xi=0;
                for i=1:n
                    x=a+(3*i-2)*h;
                    xi=xi+g(x);
                    %punto xn-------------------------
                    plot(app.UIAxes,x,feval(g,x));
                    hold (app.UIAxes, 'on');
                    yg=linspace(0,g(x));
                    plot(app.UIAxes,x,yg);
                    %--------------------------------
                    XV(i)=x;
                end
            xj=0;   
                for k=1:n
                    y=a+(3*k-1)*h;
                    xj=xj+g(y);
                    %punto xn-------------------------
                    plot(app.UIAxes,y,feval(g,y));
                    yg=linspace(0,g(y));
                    plot(app.UIAxes,y,yg);
                    %--------------------------------
                    XY(k)=y;
                end
            xk=0;   
                for p=1:n-1
                    z=a+(3*p)*h;
                    xk=xk+g(z);
                    %punto xn-------------------------
                    plot(app.UIAxes,z,feval(g,z));
                    yg=linspace(0,g(z));
                    plot(app.UIAxes,z,yg);
                    %--------------------------------
                    XZ(p)=z;
                end
                %vector de x
                if n==1
                    Xvectors=[a b XV XY];
                else
                    Xvectors=[a b XV XY XZ];
                end   
                Xvectors=sort(Xvectors);

                auxj=1;
                for i=1:n
                    for j=1:4
                        XGi(i,j)=Xvectors(auxj);
                        auxj=auxj+1;
                        if j==4
                            auxj=auxj-1;
                        end
                    end
                    %Creacion de polinomio de 3er grado
                    Pn=interpolacionLagrange(app,XGi(i,:),g(XGi(i,:)));
                    Pn=inline(Pn);

                    %Graficar funcion de 3er grado
                    xgPn=XGi(i,1):0.01:XGi(i,4);
                    area(app.UIAxes, xgPn, feval(Pn,xgPn),'FaceColor', '#4DBEEE','LineStyle',"--");
                    plot(app.UIAxes,xgPn, feval(Pn,xgPn),'LineWidth',2); 
                end

             %resultado
             result=(3*h/8)*(g(a)+g(b)+3*xi+3*xj+2*xk);
             %2+sin(2*sqrt(x))

             %Grafica
             app.resultado.Value = num2str(result);
             X=a:h:b;
             x=a:0.01:b;
             %area(app.UIAxes, X, feval(g,X),'FaceColor', 'g');
             plot(app.UIAxes,x, feval(g,x),'LineWidth',1);

             %a
             plot(app.UIAxes,a,feval(g,a),'');
             ya=linspace(0,g(a));
             plot(app.UIAxes,a,ya);

             %b
             plot(app.UIAxes,b,feval(g,b));
             yb=linspace(0,g(b));
             plot(app.UIAxes,b,yb);
             
             app.calcular.Enable = 'off';
             app.a.Editable = 'off';
             app.b.Editable = 'off';
             app.ecuacion.Editable = 'off';

        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
            g = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;

            % --- Method
            h=(b-a)/(3*n);
            xi=0;
                for i=1:n
                    x=a+(3*i-2)*h;
                    xi=xi+g(x);
                    %punto xn-------------------------
                    plot(app.UIAxes,x,feval(g,x),'*');
                    hold (app.UIAxes, 'on');
                    yg=linspace(0,g(x));
                    plot(app.UIAxes,x,yg);
                    %--------------------------------
                    XV(i)=x;
                end
            xj=0;   
                for k=1:n
                    y=a+(3*k-1)*h;
                    xj=xj+g(y);
                    %punto xn-------------------------
                    plot(app.UIAxes,y,feval(g,y),'*');
                    yg=linspace(0,g(y));
                    plot(app.UIAxes,y,yg);
                    %--------------------------------
                    XY(k)=y;
                end
            xk=0;   
                for p=1:n-1
                    z=a+(3*p)*h;
                    xk=xk+g(z);
                    %punto xn-------------------------
                    plot(app.UIAxes,z,feval(g,z),'*');
                    yg=linspace(0,g(z));
                    plot(app.UIAxes,z,yg);
                    %--------------------------------
                    XZ(p)=z;
                end
                %vector de x
                if n==1
                    Xvectors=[a b XV XY];
                else
                    Xvectors=[a b XV XY XZ];
                end   
                Xvectors=sort(Xvectors);

                auxj=1;
                for i=1:n
                    for j=1:4
                        XGi(i,j)=Xvectors(auxj);
                        auxj=auxj+1;
                        if j==4
                            auxj=auxj-1;
                        end
                    end
                    %Creacion de polinomio de 3er grado
                    Pn=interpolacionLagrange(app,XGi(i,:),g(XGi(i,:)));
                    Pn=inline(Pn);

                    %Graficar funcion de 3er grado
                    xgPn=XGi(i,1):0.01:XGi(i,4);
                    area(app.UIAxes, xgPn, feval(Pn,xgPn),'FaceColor', '#4DBEEE','LineStyle',"--");
                    plot(app.UIAxes,xgPn, feval(Pn,xgPn),'LineWidth',2); 
                end

             %resultado
             result=(3*h/8)*(g(a)+g(b)+3*xi+3*xj+2*xk);
             %2+sin(2*sqrt(x))

             %Grafica
             app.resultado.Value = num2str(result);
             X=a:h:b;
             x=a-1:0.01:b+1;
             %area(app.UIAxes, X, feval(g,X),'FaceColor', 'g');
             

             %a
             plot(app.UIAxes,a,feval(g,a),'*');
             ya=linspace(0,g(a));
             plot(app.UIAxes,a,ya);

             %b
             plot(app.UIAxes,b,feval(g,b),'*');
             yb=linspace(0,g(b));
             plot(app.UIAxes,b,yb);
             
             app.calcular.Enable = 'off';
             app.a.Editable = 'off';
             app.b.Editable = 'off';
             app.ecuacion.Editable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MtododeSimpson38Panel = uipanel(app.UIFigure);
            app.MtododeSimpson38Panel.Title = 'Método de Simpson 3/8';
            app.EcuacinEditFieldLabel = uilabel(app.MtododeSimpson38Panel);
            app.EcuacinEditFieldLabel.Text = 'Ecuación';
            app.ecuacion = uieditfield(app.MtododeSimpson38Panel, 'text');
            app.DesdeaEditFieldLabel = uilabel(app.MtododeSimpson38Panel);
            app.DesdeaEditFieldLabel.Text = 'Desde (a):';
            app.a = uieditfield(app.MtododeSimpson38Panel, 'numeric');
            app.HastabEditFieldLabel = uilabel(app.MtododeSimpson38Panel);
            app.HastabEditFieldLabel.Text = 'Hasta (b)';
            app.b = uieditfield(app.MtododeSimpson38Panel, 'numeric');
            app.NumerodePolinomiosLabel = uilabel(app.MtododeSimpson38Panel);
            app.NumerodePolinomiosLabel.Text = 'Número de Polinomios';
            app.n = uieditfield(app.MtododeSimpson38Panel, 'numeric');
            app.calcular = uibutton(app.MtododeSimpson38Panel, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.MtododeSimpson38Panel);
            app.ResultadoLabel.Text = 'Resultado';
            app.resultado = uieditfield(app.MtododeSimpson38Panel, 'text');
            app.resultado.Editable = 'off';
            app.AadirButton = uibutton(app.MtododeSimpson38Panel, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Text = 'Añadir';
            app.GrficaPanel = uipanel(app.UIFigure);
            app.GrficaPanel.Title = 'Gráfica';
            app.UIAxes = uiaxes(app.GrficaPanel);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simpson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end