classdef Trapecios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MtododetrapeciosPanel  matlab.ui.container.Panel
        EcuacinEditFieldLabel  matlab.ui.control.Label
        ecuacion               matlab.ui.control.EditField
        DesdeaEditFieldLabel   matlab.ui.control.Label
        a                      matlab.ui.control.NumericEditField
        HastabEditFieldLabel   matlab.ui.control.Label
        b                      matlab.ui.control.NumericEditField
        NumerodetrapeciosEditFieldLabel  matlab.ui.control.Label
        n                      matlab.ui.control.NumericEditField
        calcular               matlab.ui.control.Button
        ResultadoLabel         matlab.ui.control.Label
        resultado              matlab.ui.control.EditField
        AadirButton            matlab.ui.control.Button
        GrficaPanel            matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
            
            y = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;
            h=abs(b-a)/n;
            sum = 0;
            for i=1:n-1
                sum = sum + y(a+i*h);
            end
            r = (h/2) *(y(a)+2*sum+y(b));
            app.resultado.Value = num2str(r);
            X=a:h:b;
            x=a:0.01:b;
            
            hold (app.UIAxes, 'on');
            s = strcat('Trapecios:',num2str(n));
            t = strcat('AreaTrapecios:',num2str(n));
            area(app.UIAxes, X, feval(y,X),'LineStyle',"--",'DisplayName',t);
            plot(app.UIAxes,X, feval(y,X),'LineWidth',1,'LineStyle',"--",'DisplayName',s);
            plot(app.UIAxes,x, feval(y,x),'LineWidth',2,'DisplayName','fx');
           
            legend(app.UIAxes,"AutoUpdate","on");
            
            app.calcular.Enable = 'off';
            app.a.Editable = 'off';
            app.b.Editable = 'off';
            app.ecuacion.Editable = 'off';
            
        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
            y = inline(app.ecuacion.Value);
            a = app.a.Value;
            b = app.b.Value;
            n = app.n.Value;
            h=abs(b-a)/n;
            sum = 0;
            for i=1:n-1
                sum = sum + y(a+i*h);
            end
            r = (h/2) *(y(a)+2*sum+y(b));
            app.resultado.Value = num2str(r);
            X=a:h:b;
            x=a:0.01:b;
            hold (app.UIAxes, 'on');
            s = strcat('Trapecios:',num2str(n));
            t = strcat('AreaTrapecios:',num2str(n));
            
            area(app.UIAxes, X, feval(y,X),'LineStyle',"--",'DisplayName',t);
            plot(app.UIAxes,X, feval(y,X),'LineWidth',1,'LineStyle',"--",'DisplayName',s);
            plot(app.UIAxes,x, feval(y,x),'LineWidth',2,'DisplayName','fx');
            
            legend(app.UIAxes,"AutoUpdate","on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MtododetrapeciosPanel = uipanel(app.UIFigure);
            app.MtododetrapeciosPanel.Title = 'Método de trapecios';
            app.EcuacinEditFieldLabel = uilabel(app.MtododetrapeciosPanel);
            app.EcuacinEditFieldLabel.Text = 'Ecuación';
            app.ecuacion = uieditfield(app.MtododetrapeciosPanel, 'text');
            app.DesdeaEditFieldLabel = uilabel(app.MtododetrapeciosPanel);
            app.DesdeaEditFieldLabel.Text = 'Desde (a):';
            app.a = uieditfield(app.MtododetrapeciosPanel, 'numeric');
            app.HastabEditFieldLabel = uilabel(app.MtododetrapeciosPanel);
            app.HastabEditFieldLabel.Text = 'Hasta (b)';
            app.b = uieditfield(app.MtododetrapeciosPanel, 'numeric');
            app.NumerodetrapeciosEditFieldLabel = uilabel(app.MtododetrapeciosPanel);
            app.NumerodetrapeciosEditFieldLabel.Text = 'Numero de trapecios';
            app.n = uieditfield(app.MtododetrapeciosPanel, 'numeric');
            app.n.Limits = [0 Inf];
            app.calcular = uibutton(app.MtododetrapeciosPanel, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.MtododetrapeciosPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.resultado = uieditfield(app.MtododetrapeciosPanel, 'text');
            app.resultado.Editable = 'off';
            app.AadirButton = uibutton(app.MtododetrapeciosPanel, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Text = 'Añadir';
            app.GrficaPanel = uipanel(app.UIFigure);
            app.GrficaPanel.Title = 'Gráfica';
            app.UIAxes = uiaxes(app.GrficaPanel);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trapecios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end